classdef ISP_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        converterUIFigure             matlab.ui.Figure
        rawfromatPanel                matlab.ui.container.Panel
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        pixeloffsetEditFieldLabel     matlab.ui.control.Label
        pixeloffsetEditField          matlab.ui.control.NumericEditField
        dataformatDropDownLabel       matlab.ui.control.Label
        dataformatDropDown            matlab.ui.control.DropDown
        bayerorderDropDownLabel       matlab.ui.control.Label
        bayerorderDropDown            matlab.ui.control.DropDown
        bitdepthEditFieldLabel        matlab.ui.control.Label
        bitdepthEditField             matlab.ui.control.NumericEditField
        ISPsettingPanel               matlab.ui.container.Panel
        digitalgainEditFieldLabel     matlab.ui.control.Label
        digitalgainEditField          matlab.ui.control.NumericEditField
        manualWBCheckBox              matlab.ui.control.CheckBox
        REditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        GEditFieldLabel               matlab.ui.control.Label
        GEditField                    matlab.ui.control.NumericEditField
        BEditFieldLabel               matlab.ui.control.Label
        BEditField                    matlab.ui.control.NumericEditField
        manualCCMCheckBox             matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        gammaEditFieldLabel           matlab.ui.control.Label
        gammaEditField                matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        LoadrawButton                 matlab.ui.control.Button
        LoadCalibrationFilematButton  matlab.ui.control.Button
        UnloadCalibrationButton       matlab.ui.control.Button
        DSMCalgorithmButtonGroup_2    matlab.ui.container.ButtonGroup
        HamiltonAdamsButton_2         matlab.ui.control.RadioButton
        NearistneighborButton         matlab.ui.control.RadioButton
        MarvarButton_2                matlab.ui.control.RadioButton
        ProcessShowButton             matlab.ui.control.StateButton
        DHCXISPSimulatorLabel         matlab.ui.control.Label
        ISPPipelineTOPPanel           matlab.ui.container.Panel
        blcCheckBox                   matlab.ui.control.CheckBox
        lscCheckBox                   matlab.ui.control.CheckBox
        wbCheckBox                    matlab.ui.control.CheckBox
        ccmCheckBox                   matlab.ui.control.CheckBox
        gammaCheckBox                 matlab.ui.control.CheckBox
        dgainCheckBox                 matlab.ui.control.CheckBox
        dmscCheckBox                  matlab.ui.control.CheckBox
        NRCheckBox                    matlab.ui.control.CheckBox
        dumpmeteringCheckBox          matlab.ui.control.CheckBox
        AWBalgorithmButtonGroup       matlab.ui.container.ButtonGroup
        GreyWorldButton               matlab.ui.control.RadioButton
        WhitePointButton              matlab.ui.control.RadioButton
        GridBasedButton               matlab.ui.control.RadioButton
        AMeteringButtonGroup          matlab.ui.container.ButtonGroup
        AESwitchButton                matlab.ui.control.RadioButton
        AWBSwitchButton               matlab.ui.control.RadioButton
        AFSwithButton                 matlab.ui.control.RadioButton
    end


    properties (Access = public)  % global variables
        input_frame;
        
        input_black_frame;
        input_ls_frame;
        input_ccm_frame;
        
        dsmc_algo;
        blc_type;
        im_info;
        isp;
        params;
    end
    
    
    properties (Access = private)
        file_path_name;
        main_handle_child;
    end

    
    methods (Access = private)
        
%         function results = close_rawchild1(app)
%             app.main_handle_child.openrawButton.Enable = 'on';
%             results = 0;
%             delete(app)
%             
%         end
        
    end
    
    
    methods (Access = public)
        
%         function raw = imread_raw(app, fname, info)
%                  raw = fread(fname, [info.width, info.height], 'ubit16=>uint16', endian);
%                  raw = raw(1:info.width, :)'; 
%         
%         end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
%            app.file_path_name = [main_handle.path main_handle.file];
%            app.main_handle_child = main_handle;

             app.UITable.Data = [1 0 0; 0 1 0; 0 0 1];
             app.UITable.RowName = {'1', '2' '3'};
             
             app.input_frame;
             
             app.input_black_frame;
             app.input_ls_frame; 
             app.input_ccm_frame;
             
             app.blc_type = 'bl_img';
             
             app.im_info.sensor = 'imx290';
             app.im_info.type = 'raw';
             app.im_info.cell = 'Bayer';
             
             app.im_info.rawWidth = app.WidthEditField.Value;
             app.im_info.rawHeight = app.HeightEditField.Value;
             app.im_info.bit_depth = app.bitdepthEditField.Value;
             app.im_info.pixel_offset = app.pixeloffsetEditField.Value;
             
             app.im_info.bayer = app.bayerorderDropDown.Value;
             app.im_info.data_format = app.dataformatDropDown.Value;
             
             app.isp;
             app.isp.dg = app.digitalgainEditField.Value;
             
             app.isp.dsmc_algo = 'Hamilton & Adams';
             
             app.isp.WB = [1 1 1];
             app.isp.CCM = [1 0 0; 0 1 0; 0 0 1];

             app.isp.gamma = app.gammaEditField.Value;

             app.isp.dg_enable = app.dgainCheckBox.Value;
             app.isp.blc_enable = app.blcCheckBox.Value;
             app.isp.lsc_enable = app.lscCheckBox.Value;
             app.isp.wb_enable = app.wbCheckBox.Value;
             app.isp.ccm_enable = app.ccmCheckBox.Value;
             app.isp.gamma_enable = app.gammaCheckBox.Value;
             
             app.params;
             
             
        end

        % Close request function: converterUIFigure
        function converterUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: LoadrawButton
        function LoadrawButtonPushed(app, event)
            addpath(genpath('.\'));
            [FileName, PathName] = uigetfile([cd, '\', '*.raw*'], 'Select the raw image');
            filename = [PathName, FileName];
            
            app.input_frame = filename;
            [~, params.file_name, ~] = fileparts(app.input_frame);
            
        end

        % Value changed function: pixeloffsetEditField
        function pixeloffsetEditFieldValueChanged(app, event)
            app.im_info.pixel_offset = app.pixeloffsetEditField.Value;
            
        end

        % Value changed function: ProcessShowButton
        function ProcessShowButtonValueChanged(app, event)
            addpath(genpath('.\'));
            
            app.ProcessShowButton.Value = 0;
            
            % raw format info
            width =  app.WidthEditField.Value;
            height = app.HeightEditField.Value;
            
            bayer_order = app.bayerorderDropDown.Value;
            endian = app.dataformatDropDown.Value;
            
            im_info = app.im_info;
            isp = app.isp;
            params = app.params;
            
            % BLC
            if isfield(params, 'blc')
                isp.bkd = params.blc;
            else
                isp.bkd = 0;
            end
            
            % LSC
            if isfield(params, 'ls_map')
                isp.lsc = params.ls_map;
            else
                isp.lsc = 1;
            end
            
            % DMSC & WB & CCM & GAMMA
            
            % CCM
            if app.manualCCMCheckBox.Value == 1
                isp.CCM = app.UITable.Data;
                
            else
                if isfield(params, 'ccm')
                    isp.CCM = params.ccm;
                else
                    isp.CCM = [1 0 0; 0 1 0; 0 0 1];
                end
            end
            
            % WB
            if app.manualWBCheckBox.Value == 1
                isp.WB = [app.REditField.Value; app.GEditField.Value; app.BEditField.Value];
            else
                if isfield(params, 'wb')
                    isp.WB = params.wb;
                else
                    isp.WB = [1; 1; 1];
                end
            end
            
            % Digital gain
            if app.dgainCheckBox.Value == 1
                isp.dg = app.digitalgainEditField.Value;
            else
                isp.dg = 1;
            end
            
            [img, im_info] = readImg_03(app.input_frame, im_info);
            [~, params.file_name, ~] = fileparts(app.input_frame);
            [img_gamma, data_out] = ISP_pipeline_03(img, im_info, isp, params);
            
        end

        % Selection changed function: DSMCalgorithmButtonGroup_2
        function DSMCalgorithmButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.DSMCalgorithmButtonGroup_2.SelectedObject;
            app.isp.dsmc_algo = selectedButton.Text;
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.im_info.rawWidth = app.WidthEditField.Value;
            
        end

        % Value changed function: bayerorderDropDown
        function bayerorderDropDownValueChanged(app, event)
            app.im_info.bayer = app.bayerorderDropDown.Value;
            
        end

        % Value changed function: manualWBCheckBox
        function manualWBCheckBoxValueChanged(app, event)
            value = app.manualWBCheckBox.Value;
            
            if app.manualWBCheckBox.Value == 1
                app.REditField.Editable = 1;
                app.GEditField.Editable = 1;
                app.BEditField.Editable = 1;
                app.isp.wb = [app.REditField.Value; app.GEditField.Value; app.BEditField.Value];
            else
                app.REditField.Editable = 0;
                app.GEditField.Editable = 0;
                app.BEditField.Editable = 0;
            end
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.isp.gamma = app.EditField.Value;
        end

        % Value changed function: digitalgainEditField
        function digitalgainEditFieldValueChanged(app, event)
            value = app.digitalgainEditField.Value;
            
            if app.dgainCheckBox == 1
                app.isp.dg = app.digitalgainEditField.Value;
            else
                app.isp.dg = 1;
            end
            
        end

        % Value changed function: dmscCheckBox
        function dmscCheckBoxValueChanged(app, event)
            value = app.dmscCheckBox.Value;
            
            app.dmscCheckBox.Value = 1;
            
        end

        % Value changed function: dgainCheckBox
        function dgainCheckBoxValueChanged(app, event)
            value = app.dgainCheckBox.Value;
            
            if app.dgainCheckBox.Value == 0
                app.isp.dg = 1;
            end
            
        end

        % Value changed function: gammaEditField
        function gammaEditFieldValueChanged(app, event)
            value = app.gammaEditField.Value;
            
            if app.gammaCheckBox == 1
                app.isp.gamma = app.gammaEditField.Value;
            else
                app.isp.gamma = 1;
            end
            
        end

        % Button pushed function: LoadCalibrationFilematButton
        function LoadCalibrationFilematButtonPushed(app, event)
            addpath(genpath('.\'));
            [FileName, PathName] = uigetfile([cd, '\', '*.mat*'], 'Select calibration file');
            cal_file = [PathName, FileName];
            
            temp = load(cal_file);
            app.params = temp.params;
            app.REditField.Value = app.params.wb(1);
            app.GEditField.Value = app.params.wb(2);
            app.BEditField.Value = app.params.wb(3);
            app.UITable.Data = app.params.ccm;
            
        end

        % Value changed function: manualCCMCheckBox
        function manualCCMCheckBoxValueChanged(app, event)
            value = app.manualCCMCheckBox.Value;
            
            if app.manualCCMCheckBox.Value == 1
                app.isp.ccm = app.UITable.Data;
            end
            
        end

        % Button pushed function: UnloadCalibrationButton
        function UnloadCalibrationButtonPushed(app, event)
            app.params = [];
            app.REditField.Value = 1;
            app.GEditField.Value = 1;
            app.BEditField.Value = 1;
            app.UITable.Data = [1 0 0; 0 1 0; 0 0 1];
            
        end

        % Value changed function: dataformatDropDown
        function dataformatDropDownValueChanged(app, event)
            value = app.dataformatDropDown.Value;
            
            app.im_info.data_format = app.dataformatDropDown.Value;
            
        end

        % Value changed function: bitdepthEditField
        function bitdepthEditFieldValueChanged(app, event)
            value = app.bitdepthEditField.Value;
            
            app.im_info.bit_depth = app.bitdepthEditField.Value;
            
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            
            app.im_info.rawHeight = app.HeightEditField.Value;
            
        end

        % Value changed function: blcCheckBox
        function blcCheckBoxValueChanged(app, event)
            value = app.blcCheckBox.Value;
            
        end

        % Value changed function: lscCheckBox
        function lscCheckBoxValueChanged(app, event)
            value = app.lscCheckBox.Value;
            
        end

        % Value changed function: wbCheckBox
        function wbCheckBoxValueChanged(app, event)
            value = app.wbCheckBox.Value;
            
        end

        % Value changed function: ccmCheckBox
        function ccmCheckBoxValueChanged(app, event)
            value = app.ccmCheckBox.Value;
            
        end

        % Value changed function: gammaCheckBox
        function gammaCheckBoxValueChanged(app, event)
            value = app.gammaCheckBox.Value;
            
        end

        % Value changed function: NRCheckBox
        function NRCheckBoxValueChanged(app, event)
            value = app.NRCheckBox.Value;
            
        end

        % Value changed function: dumpmeteringCheckBox
        function dumpmeteringCheckBoxValueChanged(app, event)
            value = app.dumpmeteringCheckBox.Value;
            
        end

        % Selection changed function: AWBalgorithmButtonGroup
        function AWBalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.AWBalgorithmButtonGroup.SelectedObject;
            
        end

        % Selection changed function: AMeteringButtonGroup
        function AMeteringButtonGroupSelectionChanged(app, event)
            selectedButton = app.AMeteringButtonGroup.SelectedObject;
            
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            
        end

        % Value changed function: GEditField
        function GEditFieldValueChanged(app, event)
            value = app.GEditField.Value;
            
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            value = app.BEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.converterUIFigure = uifigure('Visible', 'off');
            app.converterUIFigure.Name = 'converter';
            app.converterUIFigure.CloseRequestFcn = createCallbackFcn(app, @converterUIFigureCloseRequest, true);
            app.rawfromatPanel = uipanel(app.converterUIFigure);
            app.rawfromatPanel.Title = 'raw fromat';
            app.WidthEditFieldLabel = uilabel(app.rawfromatPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.rawfromatPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 1920;
            app.HeightEditFieldLabel = uilabel(app.rawfromatPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.rawfromatPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 1080;
            app.pixeloffsetEditFieldLabel = uilabel(app.rawfromatPanel);
            app.pixeloffsetEditFieldLabel.Text = 'pixel offset';
            app.pixeloffsetEditField = uieditfield(app.rawfromatPanel, 'numeric');
            app.pixeloffsetEditField.ValueChangedFcn = createCallbackFcn(app, @pixeloffsetEditFieldValueChanged, true);
            app.dataformatDropDownLabel = uilabel(app.rawfromatPanel);
            app.dataformatDropDownLabel.Text = 'data format';
            app.dataformatDropDown = uidropdown(app.rawfromatPanel);
            app.dataformatDropDown.Items = {'ieee-le', 'ieee-be'};
            app.dataformatDropDown.ValueChangedFcn = createCallbackFcn(app, @dataformatDropDownValueChanged, true);
            app.dataformatDropDown.Value = 'ieee-le';
            app.bayerorderDropDownLabel = uilabel(app.rawfromatPanel);
            app.bayerorderDropDownLabel.Text = 'bayer order';
            app.bayerorderDropDown = uidropdown(app.rawfromatPanel);
            app.bayerorderDropDown.Items = {'rggb', 'grbg', 'gbrg', 'bggr'};
            app.bayerorderDropDown.ValueChangedFcn = createCallbackFcn(app, @bayerorderDropDownValueChanged, true);
            app.bayerorderDropDown.Value = 'rggb';
            app.bitdepthEditFieldLabel = uilabel(app.rawfromatPanel);
            app.bitdepthEditFieldLabel.Text = 'bit depth';
            app.bitdepthEditField = uieditfield(app.rawfromatPanel, 'numeric');
            app.bitdepthEditField.ValueChangedFcn = createCallbackFcn(app, @bitdepthEditFieldValueChanged, true);
            app.bitdepthEditField.Value = 12;
            app.ISPsettingPanel = uipanel(app.converterUIFigure);
            app.ISPsettingPanel.Title = 'ISP setting';
            app.digitalgainEditFieldLabel = uilabel(app.ISPsettingPanel);
            app.digitalgainEditFieldLabel.Text = 'digital gain';
            app.digitalgainEditField = uieditfield(app.ISPsettingPanel, 'numeric');
            app.digitalgainEditField.Limits = [0 Inf];
            app.digitalgainEditField.ValueDisplayFormat = '%5.2f';
            app.digitalgainEditField.ValueChangedFcn = createCallbackFcn(app, @digitalgainEditFieldValueChanged, true);
            app.digitalgainEditField.Value = 1;
            app.manualWBCheckBox = uicheckbox(app.ISPsettingPanel);
            app.manualWBCheckBox.ValueChangedFcn = createCallbackFcn(app, @manualWBCheckBoxValueChanged, true);
            app.manualWBCheckBox.Text = 'manual WB';
            app.REditFieldLabel = uilabel(app.ISPsettingPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ISPsettingPanel, 'numeric');
            app.REditField.Limits = [0 Inf];
            app.REditField.ValueDisplayFormat = '%.3f';
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Editable = 'off';
            app.REditField.Value = 1;
            app.GEditFieldLabel = uilabel(app.ISPsettingPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.ISPsettingPanel, 'numeric');
            app.GEditField.Limits = [0 Inf];
            app.GEditField.ValueDisplayFormat = '%.3f';
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @GEditFieldValueChanged, true);
            app.GEditField.Editable = 'off';
            app.GEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.ISPsettingPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ISPsettingPanel, 'numeric');
            app.BEditField.Limits = [0 Inf];
            app.BEditField.ValueDisplayFormat = '%.3f';
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Editable = 'off';
            app.BEditField.Value = 1;
            app.manualCCMCheckBox = uicheckbox(app.ISPsettingPanel);
            app.manualCCMCheckBox.ValueChangedFcn = createCallbackFcn(app, @manualCCMCheckBoxValueChanged, true);
            app.manualCCMCheckBox.Text = 'manual CCM';
            app.UITable = uitable(app.ISPsettingPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.gammaEditFieldLabel = uilabel(app.ISPsettingPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.ISPsettingPanel, 'numeric');
            app.gammaEditField.Limits = [0 Inf];
            app.gammaEditField.ValueDisplayFormat = '%5.2f';
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @gammaEditFieldValueChanged, true);
            app.gammaEditField.Value = 2.2;
            app.Panel = uipanel(app.converterUIFigure);
            app.LoadrawButton = uibutton(app.Panel, 'push');
            app.LoadrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawButtonPushed, true);
            app.LoadrawButton.Text = 'Load raw';
            app.LoadCalibrationFilematButton = uibutton(app.Panel, 'push');
            app.LoadCalibrationFilematButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationFilematButtonPushed, true);
            app.LoadCalibrationFilematButton.Text = {'Load Calibration File'; '(.mat)'};
            app.UnloadCalibrationButton = uibutton(app.Panel, 'push');
            app.UnloadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadCalibrationButtonPushed, true);
            app.UnloadCalibrationButton.Text = 'Unload Calibration';
            app.DSMCalgorithmButtonGroup_2 = uibuttongroup(app.converterUIFigure);
            app.DSMCalgorithmButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DSMCalgorithmButtonGroup_2SelectionChanged, true);
            app.DSMCalgorithmButtonGroup_2.Title = 'DSMC algorithm';
            app.HamiltonAdamsButton_2 = uiradiobutton(app.DSMCalgorithmButtonGroup_2);
            app.HamiltonAdamsButton_2.Text = 'Hamilton & Adams';
            app.HamiltonAdamsButton_2.Value = true;
            app.NearistneighborButton = uiradiobutton(app.DSMCalgorithmButtonGroup_2);
            app.NearistneighborButton.Text = 'Nearist neighbor';
            app.MarvarButton_2 = uiradiobutton(app.DSMCalgorithmButtonGroup_2);
            app.MarvarButton_2.Text = 'Marvar';
            app.ProcessShowButton = uibutton(app.converterUIFigure, 'state');
            app.ProcessShowButton.ValueChangedFcn = createCallbackFcn(app, @ProcessShowButtonValueChanged, true);
            app.ProcessShowButton.Text = 'Process & Show';
            app.DHCXISPSimulatorLabel = uilabel(app.converterUIFigure);
            app.DHCXISPSimulatorLabel.Text = 'DHCX ISP Simulator';
            app.ISPPipelineTOPPanel = uipanel(app.converterUIFigure);
            app.ISPPipelineTOPPanel.Title = 'ISP Pipeline TOP';
            app.blcCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.blcCheckBox.ValueChangedFcn = createCallbackFcn(app, @blcCheckBoxValueChanged, true);
            app.blcCheckBox.Text = 'blc';
            app.blcCheckBox.Value = true;
            app.lscCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.lscCheckBox.ValueChangedFcn = createCallbackFcn(app, @lscCheckBoxValueChanged, true);
            app.lscCheckBox.Text = 'lsc';
            app.lscCheckBox.Value = true;
            app.wbCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.wbCheckBox.ValueChangedFcn = createCallbackFcn(app, @wbCheckBoxValueChanged, true);
            app.wbCheckBox.Text = 'wb';
            app.wbCheckBox.Value = true;
            app.ccmCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.ccmCheckBox.ValueChangedFcn = createCallbackFcn(app, @ccmCheckBoxValueChanged, true);
            app.ccmCheckBox.Text = 'ccm';
            app.ccmCheckBox.Value = true;
            app.gammaCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.gammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @gammaCheckBoxValueChanged, true);
            app.gammaCheckBox.Text = 'gamma';
            app.gammaCheckBox.Value = true;
            app.dgainCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.dgainCheckBox.ValueChangedFcn = createCallbackFcn(app, @dgainCheckBoxValueChanged, true);
            app.dgainCheckBox.Text = 'dgain';
            app.dgainCheckBox.Value = true;
            app.dmscCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.dmscCheckBox.ValueChangedFcn = createCallbackFcn(app, @dmscCheckBoxValueChanged, true);
            app.dmscCheckBox.Text = 'dmsc';
            app.dmscCheckBox.Value = true;
            app.NRCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.NRCheckBox.ValueChangedFcn = createCallbackFcn(app, @NRCheckBoxValueChanged, true);
            app.NRCheckBox.Text = 'NR';
            app.NRCheckBox.Value = true;
            app.dumpmeteringCheckBox = uicheckbox(app.ISPPipelineTOPPanel);
            app.dumpmeteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @dumpmeteringCheckBoxValueChanged, true);
            app.dumpmeteringCheckBox.Text = 'dump metering';
            app.dumpmeteringCheckBox.Value = true;
            app.AWBalgorithmButtonGroup = uibuttongroup(app.converterUIFigure);
            app.AWBalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AWBalgorithmButtonGroupSelectionChanged, true);
            app.AWBalgorithmButtonGroup.Title = 'AWB algorithm';
            app.GreyWorldButton = uiradiobutton(app.AWBalgorithmButtonGroup);
            app.GreyWorldButton.Text = 'Grey World';
            app.GreyWorldButton.Value = true;
            app.WhitePointButton = uiradiobutton(app.AWBalgorithmButtonGroup);
            app.WhitePointButton.Text = 'White Point';
            app.GridBasedButton = uiradiobutton(app.AWBalgorithmButtonGroup);
            app.GridBasedButton.Text = 'Grid Based';
            app.AMeteringButtonGroup = uibuttongroup(app.converterUIFigure);
            app.AMeteringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AMeteringButtonGroupSelectionChanged, true);
            app.AMeteringButtonGroup.Title = '3A Metering';
            app.AESwitchButton = uiradiobutton(app.AMeteringButtonGroup);
            app.AESwitchButton.Text = 'AE Switch';
            app.AESwitchButton.Value = true;
            app.AWBSwitchButton = uiradiobutton(app.AMeteringButtonGroup);
            app.AWBSwitchButton.Text = 'AWB Switch';
            app.AFSwithButton = uiradiobutton(app.AMeteringButtonGroup);
            app.AFSwithButton.Text = 'AF Swith';
            % Show the figure after all components are created
            app.converterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISP_Simulator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.converterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.converterUIFigure)
        end
    end
end